classdef WO_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ChoosemethodButtonGroup  matlab.ui.container.ButtonGroup
        SelfoptimizingcontrolNullspacemethodButton  matlab.ui.control.RadioButton
        NeighbouringextremalcontrolButton  matlab.ui.control.RadioButton
        TwostepapproachButton    matlab.ui.control.RadioButton
        FeedbackRTOButton        matlab.ui.control.RadioButton
        ClassicalextremumseekingcontrolButton  matlab.ui.control.RadioButton
        LeastsquaresbasedextremumseekingcontrolButton  matlab.ui.control.RadioButton
        KalmanfilterbasedextremumseekingcontrolButton  matlab.ui.control.RadioButton
        FFTbasedextremumseekingcontrolButton  matlab.ui.control.RadioButton
        RadialbasisfunctionbasedgradientestimationButton  matlab.ui.control.RadioButton
        RunButton                matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            addpath(genpath(pwd))
            if app.SelfoptimizingcontrolNullspacemethodButton.Value == true
                WO_SOC
            elseif app.NeighbouringextremalcontrolButton.Value == true
                WO_NEC
            elseif app.TwostepapproachButton.Value == true
                WO_TSA
            elseif app.FeedbackRTOButton.Value == true
                WO_FRTO
            elseif app.ClassicalextremumseekingcontrolButton.Value == true
                WO_ESC
            elseif app.LeastsquaresbasedextremumseekingcontrolButton.Value == true
                WO_LSE
            elseif app.KalmanfilterbasedextremumseekingcontrolButton.Value == true
                WO_KF
            elseif app.FFTbasedextremumseekingcontrolButton.Value == true
                WO_FFT
            elseif app.RadialbasisfunctionbasedgradientestimationButton.Value == true
                WO_RBF
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ChoosemethodButtonGroup = uibuttongroup(app.UIFigure);
            app.ChoosemethodButtonGroup.Title = 'Choose method';
            app.SelfoptimizingcontrolNullspacemethodButton = uiradiobutton(app.ChoosemethodButtonGroup);
            app.SelfoptimizingcontrolNullspacemethodButton.Text = 'Self-optimizing control (Nullspace method)';
            app.SelfoptimizingcontrolNullspacemethodButton.Value = true;
            app.NeighbouringextremalcontrolButton = uiradiobutton(app.ChoosemethodButtonGroup);
            app.NeighbouringextremalcontrolButton.Text = 'Neighbouring extremal control';
            app.TwostepapproachButton = uiradiobutton(app.ChoosemethodButtonGroup);
            app.TwostepapproachButton.Text = 'Two-step approach';
            app.FeedbackRTOButton = uiradiobutton(app.ChoosemethodButtonGroup);
            app.FeedbackRTOButton.Text = 'Feedback RTO';
            app.ClassicalextremumseekingcontrolButton = uiradiobutton(app.ChoosemethodButtonGroup);
            app.ClassicalextremumseekingcontrolButton.Text = 'Classical extremum seeking control';
            app.LeastsquaresbasedextremumseekingcontrolButton = uiradiobutton(app.ChoosemethodButtonGroup);
            app.LeastsquaresbasedextremumseekingcontrolButton.Text = 'Least squares based extremum seeking control';
            app.KalmanfilterbasedextremumseekingcontrolButton = uiradiobutton(app.ChoosemethodButtonGroup);
            app.KalmanfilterbasedextremumseekingcontrolButton.Text = 'Kalman filter based extremum seeking control';
            app.FFTbasedextremumseekingcontrolButton = uiradiobutton(app.ChoosemethodButtonGroup);
            app.FFTbasedextremumseekingcontrolButton.Text = 'FFT based extremum seeking control';
            app.RadialbasisfunctionbasedgradientestimationButton = uiradiobutton(app.ChoosemethodButtonGroup);
            app.RadialbasisfunctionbasedgradientestimationButton.Text = 'Radial basis function based gradient estimation';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WO_main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end